classdef Bulat7GradRelayApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Toolbar                    matlab.ui.container.Toolbar
        FileOpen                   matlab.ui.container.toolbar.PushTool
        FileSave                   matlab.ui.container.toolbar.PushTool
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        GeneratorLabel             matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        ReceiverLabel              matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        ProbeDropDown              matlab.ui.control.DropDown
        ProbeDropDownLabel         matlab.ui.control.Label
        updateButton               matlab.ui.control.Button
        GeneratorChannelSwitch     matlab.ui.control.Switch
        StoreontargetCheckBox      matlab.ui.control.CheckBox
        TabGroup                   matlab.ui.container.TabGroup
        LUTTab                     matlab.ui.container.Tab
        Threshold2CodeButton       matlab.ui.control.Button
        UIThresholdTable           matlab.ui.control.Table
        msLabel                    matlab.ui.control.Label
        VelocityEditField          matlab.ui.control.NumericEditField
        VelocityEditFieldLabel     matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        ZondwidthEditField         matlab.ui.control.NumericEditField
        ZondwidthEditFieldLabel    matlab.ui.control.Label
        G00EditField               matlab.ui.control.NumericEditField
        G00EditFieldLabel          matlab.ui.control.Label
        G00_toCodeButton           matlab.ui.control.Button
        MinusButton                matlab.ui.control.Button
        PlusButton                 matlab.ui.control.Button
        UIGradTable                matlab.ui.control.Table
        UIAxes                     matlab.ui.control.UIAxes
        UIAxesGradTable            matlab.ui.control.UIAxes
        ZeroTab                    matlab.ui.container.Tab
        NC_Button                  matlab.ui.control.Button
        C_Button                   matlab.ui.control.Button
        RespSTDEditField           matlab.ui.control.NumericEditField
        RespSTDEditFieldLabel      matlab.ui.control.Label
        ZeroTabLabel_2             matlab.ui.control.Label
        LPfreqEditField            matlab.ui.control.NumericEditField
        LPfreqEditFieldLabel       matlab.ui.control.Label
        Zero_toCodeButton          matlab.ui.control.Button
        BlueZeroEditField          matlab.ui.control.NumericEditField
        Zero2Label                 matlab.ui.control.Label
        RedZeroEditField           matlab.ui.control.NumericEditField
        ZeroLabel                  matlab.ui.control.Label
        ZeroTabThresholdEditField  matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel    matlab.ui.control.Label
        AutoGainCheckBox           matlab.ui.control.CheckBox
        ZeroTabZondWidthEditField  matlab.ui.control.NumericEditField
        ZondwidthEditField_2Label  matlab.ui.control.Label
        ZeroTabLabel               matlab.ui.control.Label
        ZeroTabUIAxes              matlab.ui.control.UIAxes
        COMportDropDown            matlab.ui.control.DropDown
        COMportDropDownLabel       matlab.ui.control.Label
        SingleDualSwitch           matlab.ui.control.Switch
        ConnectButton              matlab.ui.control.Button
    end


    properties (Access = private)

        COM_Port
        SerialObj = []
        SerialHeader = uint8([0, 128, 255, 64, 0, 128, 255, 64,0, 128, 255, 64, 0, 128, 255, 64,...
            0, 128, 255, 64, 0, 128, 255, 64,0, 128, 255, 64, 0, 128, 255, 64,...
            0, 128, 255, 64, 0, 128, 255, 64,0, 128, 255, 64, 0, 128, 255, 64,...
            0, 128, 255, 64, 0, 128, 255, 64,0, 128, 255, 64, 0, 128, 255, 64]);
        SerialObjFcnInProgress = false
        IsSerialConnected = false


        Signal = zeros(1, 19944)
        SignalFiltered = zeros(1, 19944)
        G00 = 0
        Cnt = 0
        PlotAllInProgress = false
        GainCurvePointSelected = false
        GainCurvePointSelectedIdx = 1

        %Timer
        myTimer

        %Mouse
        MouseButton = 0
        MouseButtonDownX
        MouseButtonDownY

        %Line objects
        GradLUT_LineObj
        GradLUT_G00_LineObj
        GradLUT_TextObj

        SignalLineObj
        SignalFilteredLineObj
        DposLineObj
        ThresholdLineObj
        ThresholdTextObj
        GainCurveLineObj
        G00_LineObj

        ZeroTabSignalLineObj
        ZeroTabLeftBoundLineObj
        ZeroTabGainStaticLineObj
        ZeroTabZeroMarkerLineObj
        ZeroTabThresholdLineObj
        ZeroTabLeftBoundTextObj
        ZeroTabGainStaticTextObj
        ZeroTabZeroMarkerTextObj

        %Rulers
        ChangedLimitsFlag = 0

        %axToolBar
        axToolBar
        btnD
        btnThreshold
        btnGainCurve

        axZeroTabToolBar
        btnLeftBound
        btnGainStatic

        %GradTable
        GradTable = { 0.99 2138; 2.03 2199; 8.01 2337; 12.0 2629}
        GradTableCurrentIdx = 0
        GradTableMaxSize = 20

        %ThresholdTable
        ThresholdTable = { 'Max' 1676; '+4' 1676; '+3' 1676; '+2' 1676; ...
            '+1' 1676; 'Norm' 1676; '-1' 1676; '-2' 1676; ...
            'Min' 1676};
        ThresholdTableCurrentIdx = 0;

        %Settings
        SettingsIdx = 0
        Dpos = 0
        Threshold = 1676
        GainCurveX = [0 1008 2016 3024 4032 5040 6048 7002 8010 19944];
        GainCurveY = ones(1, 10) *1600;
        SingleDual = 1
        GeneratorChannel = 0
        ZondWidth = 10
        Velocity = 5920

        ZeroTabSettingsIdx = 0

        ZeroTabLeftBound = 600
        ZeroTabGainStatic = 1500
        ZeroTabThreshold = 2500
        ZeroTabZondWidth = 5
        ZeroTabZeroPosX = 0
        ZeroTabZeroPosY = 0

        ZeroTabBlueZero = 0
        ZeroTabRedZero = 0

        store
    end

    methods (Access = private)

        function myTimerFcn( app, ~, ~)
            app.Cnt = app.Cnt +1;
        end

        function axToolbarSelectionChangedFcn( app, ~, event)

            ZoomInIdx = 3;
            ZoomOutIdx = 4;

            if convertCharsToStrings( app.TabGroup.SelectedTab.Title) == "LUT"
                ZoomInIdx = 4;
                ZoomOutIdx = 5;
            end

            if event.Selection.Value == true

                ButtonsQty = length( event.Axes.Toolbar.Children);

                tag = convertCharsToStrings( event.Selection.Tag);
                idx = 1;
                for i = 1 : ( ButtonsQty -3) % i = 1:3
                    if convertCharsToStrings( event.Axes.Toolbar.Children(i).Tag) == tag
                        idx = i;
                    end
                    event.Axes.Toolbar.Children(i).Value = false;
                    %app.UIAxes.Toolbar.Children(i).Value = false;
                    SetToolBarChildrenValue( app, i, false);
                end

                % если нажали что-то кроме zommin или zoomout
                if ~( event.Axes.Toolbar.Children( ZoomInIdx).Value == true || ...
                        event.Axes.Toolbar.Children( ZoomOutIdx).Value == true)
                    event.Axes.Toolbar.Children(idx).Value = true;
                    %app.UIAxes.Toolbar.Children(idx).Value = true;
                    SetToolBarChildrenValue( app, idx, true);
                end

                SetSettingsIdx( app, idx);
                %app.SettingsIdx = idx;
            else
                SetSettingsIdx( app, 0);
                %app.SettingsIdx = 0;
            end

            function SetToolBarChildrenValue( app, index, state)
                if convertCharsToStrings( app.TabGroup.SelectedTab.Title) == "LUT"
                    app.UIAxes.Toolbar.Children( index).Value = state;
                else
                    app.ZeroTabUIAxes.Toolbar.Children( index).Value = state;
                end
            end

            function SetSettingsIdx( app, value)
                if convertCharsToStrings( app.TabGroup.SelectedTab.Title) == "LUT"
                    app.SettingsIdx = value;
                else
                    app.ZeroTabSettingsIdx = value;
                end
            end
        end

        function PlotGradLUT(app)
            x = cell2mat( app.GradTable(:,1));
            y = cell2mat( app.GradTable(:,2));
            app.GradLUT_LineObj.XData = x;
            app.GradLUT_LineObj.YData = y;
        end

        function ZeroTabPlotInit(app)
            x = 0:19943;    % Signal
            app.ZeroTabSignalLineObj = plot( app.ZeroTabUIAxes, x, app.Signal, "Color", "#0072BD", "LineStyle","-");

            hold( app.ZeroTabUIAxes,"on");

            app.SignalFilteredLineObj = plot( app.ZeroTabUIAxes, x, app.SignalFiltered, "Color", "#77AC30", "LineStyle","-");

            app.ZeroTabZeroMarkerLineObj = plot( app.ZeroTabUIAxes, 0, 0, "bo");

            x = [ app.ZeroTabLeftBound, app.ZeroTabLeftBound];  % ZeroTabLeftBound
            y = app.ZeroTabUIAxes.YLim;
            app.ZeroTabLeftBoundLineObj = plot( app.ZeroTabUIAxes, x, y, "Color", "Green", "LineStyle","-");

            x = app.ZeroTabUIAxes.XLim;
            y = [ app.ZeroTabGainStatic, app.ZeroTabGainStatic];  % ZeroTabGainStatic
            app.ZeroTabGainStaticLineObj = plot( app.ZeroTabUIAxes, x, y, "Color", "#EDB120", "LineStyle","-");
            y = [ app.ZeroTabThreshold app.ZeroTabThreshold];
            app.ZeroTabThresholdLineObj = plot( app.ZeroTabUIAxes, x, y, "Color", "#0072BD", "LineStyle","--");

            app.ZeroTabGainStaticTextObj = text( app.ZeroTabUIAxes, 0, 0, "", "HorizontalAlignment", "right", ...
                "BackgroundColor", "black", "Color", "#EDB120");
            app.ZeroTabLeftBoundTextObj = text( app.ZeroTabUIAxes, 0, 0, "", "HorizontalAlignment", "right", ...
                "BackgroundColor", "black", "Color", "Green", "Rotation", 90);
            app.ZeroTabZeroMarkerTextObj = text( app.ZeroTabUIAxes, 0, 0, "", "HorizontalAlignment", "left", ...
                "Color", "blue");

            hold( app.ZeroTabUIAxes,"off");

            app.axZeroTabToolBar = axtoolbar( app.ZeroTabUIAxes, {'zoomin','zoomout','restoreview'});
            app.axZeroTabToolBar.SelectionChangedFcn = @app.axToolbarSelectionChangedFcn;
            app.btnLeftBound = axtoolbarbtn( app.axZeroTabToolBar,'state');
            app.btnLeftBound.Icon = 'LeftBound.png';
            app.btnLeftBound.Tooltip = 'LeftBound';
            app.btnLeftBound.Tag = 'LeftBound';
            app.btnGainStatic = axtoolbarbtn( app.axZeroTabToolBar,'state');
            app.btnGainStatic.Icon = 'GainStatic.png';
            app.btnGainStatic.Tooltip = 'GainStatic';
            app.btnGainStatic.Tag = 'GainStatic';

        end

        function PlotInit(app)
            app.UIThresholdTable.Data = app.ThresholdTable;
            app.UIThresholdTable.ColumnFormat = {'char' 'numeric'};
            %app.UIThresholdTable.ColumnWidth = { 39 59};


            x = cell2mat( app.GradTable(:,1));  % Grad LUT
            y = cell2mat( app.GradTable(:,2));
            app.GradLUT_LineObj = plot( app.UIAxesGradTable, x, y, '-o');
            hold( app.UIAxesGradTable,"on");
            app.GradLUT_G00_LineObj = plot( app.UIAxesGradTable, x, y, '-o');
            app.GradLUT_TextObj = text( app.UIAxesGradTable, 0.99, 0, "", 'FontSize', 14, 'FontWeight', 'bold', ...
                'HorizontalAlignment', 'Right', 'VerticalAlignment', ...
                'Bottom', 'Units', 'Normalized', 'Color', 'black');

            yyaxis( app.UIAxes,"left")
            ylim( app.UIAxes, [0 3.0])
            ylabel( app.UIAxes, "V");

            yyaxis( app.UIAxes,"right")
            ylim( app.UIAxes, [0 4096])
            ylabel( app.UIAxes, "");
            app.UIAxes.YAxis(2).LimitsChangedFcn = @app.LimitsChgdCodeFcn;
            app.UIAxes.YAxis(1).LimitsChangedFcn = @app.LimitsChgd_mV_Fcn;
            app.UIAxes.YAxis(1).TickLabelColor = "black";
            app.UIAxes.YAxis(2).TickLabelColor = "black";
            app.UIAxes.YAxis(1).Color = "black";
            app.UIAxes.YAxis(2).Color = "black";

            x = 0:19943;    % Signal
            app.SignalLineObj = plot( app.UIAxes, x, app.Signal, "Color", "#0072BD", "LineStyle","-");
            hold( app.UIAxes,"on");

            x = [ app.Dpos, app.Dpos];  % D
            y = app.UIAxes.YLim;
            app.DposLineObj = plot( app.UIAxes, x, y, "Color", "red", "LineStyle","-");

            x = app.UIAxes.XLim;
            y = [ app.Threshold app.Threshold]; % Threshold
            app.ThresholdLineObj = plot( app.UIAxes, x, y, "Color", "blue", "LineStyle","-");

            app.ThresholdTextObj = text( app.UIAxes, 0, 0, "", "HorizontalAlignment", "right", ...
                "BackgroundColor", "white", "Color", "blue");


            if( app.G00 < 19944*2)  % G00
                G00_100 = round( app.G00 /2);
                x = [ G00_100 G00_100];
            else
                x = [0, 0];
            end
            y = app.UIAxes.YLim;
            app.G00_LineObj = plot( app.UIAxes, x, y, "Color", "green", "LineStyle","-");

            app.GainCurveLineObj = plot( app.UIAxes, app.GainCurveX, app.GainCurveY, '-o', ...
                'Color', '#EDB120'); %, 'ButtonDownFcn', @app.GainCurveLineObjButtonDownFcn); % Dark yellow
            hold( app.UIAxes,"off");

            app.axToolBar = axtoolbar( app.UIAxes, {'zoomin','zoomout','restoreview'});
            app.axToolBar.SelectionChangedFcn = @app.axToolbarSelectionChangedFcn;
            app.btnD = axtoolbarbtn( app.axToolBar,'state');
            app.btnD.Icon = 'D.png';
            app.btnD.Tooltip = 'D';
            app.btnD.Tag = 'D';
            app.btnThreshold = axtoolbarbtn( app.axToolBar,'state');
            app.btnThreshold.Icon = 'Threshold.png';
            app.btnThreshold.Tooltip = 'Threshold';
            app.btnThreshold.Tag = 'Threshold';
            app.btnGainCurve = axtoolbarbtn( app.axToolBar,'state');
            app.btnGainCurve.Icon = 'GainCurve.png';
            app.btnGainCurve.Tooltip = 'GainCurve';
            app.btnGainCurve.Tag = 'GainCurve';

            app.StoreontargetCheckBox.Enable = true;
        end

        function LimitsChgdCodeFcn( app, ~, event)
            if app.ChangedLimitsFlag == 0
                app.ChangedLimitsFlag = 1;
                CodeLim = event.NewLimits;
                if any( CodeLim > 4096)
                    CodeLim = [0 4096];
                end
                mV_Lim = CodeLim * 3.0 / 4096;
                yyaxis( app.UIAxes,"right");
                ylim( app.UIAxes, CodeLim);
                yyaxis( app.UIAxes,"left");
                ylim( app.UIAxes, mV_Lim);
            else
                app.ChangedLimitsFlag = 0;
            end

            yyaxis( app.UIAxes,"right");
        end

        function LimitsChgd_mV_Fcn( app, ~, event)
            if app.ChangedLimitsFlag == 0
                app.ChangedLimitsFlag = 1;
                mV_Lim = event.NewLimits;
                if any( mV_Lim > 3.0)
                    mV_Lim = [0 3.0];
                end
                CodeLim = mV_Lim * 4096 / 3.0;
                yyaxis( app.UIAxes,"left");
                ylim( app.UIAxes, mV_Lim);
                yyaxis( app.UIAxes,"right");
                ylim( app.UIAxes, CodeLim);
            else
                app.ChangedLimitsFlag = 0;
            end

            yyaxis( app.UIAxes,"right");
        end


        function PlotAll(app)
            if app.ChangedLimitsFlag
                %return;
            end

            if convertCharsToStrings( app.TabGroup.SelectedTab.Title) == "Zero"
                ZeroTabPlotAll(app);
            else

                yyaxis( app.UIAxes,"right");

                %if app.PlotAllInProgress == false

                app.PlotAllInProgress = true;

                x = 0:length( app.Signal)-1;
                app.SignalLineObj.XData = x;
                app.SignalLineObj.YData = app.Signal;

                x = [ app.Dpos, app.Dpos];
                y = app.UIAxes.YLim;
                app.DposLineObj.XData = x;
                app.DposLineObj.YData = y;

                x = app.UIAxes.XLim;
                y = [ app.Threshold app.Threshold];
                app.ThresholdLineObj.XData = x;
                app.ThresholdLineObj.YData = y;


                if( app.G00 < 19944*2)
                    G00_100 = round( app.G00 /2);
                    x = [ G00_100 G00_100];
                else
                    x = [0, 0];
                end
                y = app.UIAxes.YLim;
                app.G00_LineObj.XData = x;
                app.G00_LineObj.YData = y;

                app.GainCurveLineObj.XData = app.GainCurveX;
                app.GainCurveLineObj.YData = app.GainCurveY;

                app.G00EditField.Value = app.G00;

                app.ThresholdTextObj.String = sprintf( "%0.0f", app.Threshold);
                app.ThresholdTextObj.Position = [ app.UIAxes.XLim(2) app.Threshold];


                x = cell2mat( app.GradTable(:,1));
                y = cell2mat( app.GradTable(:,2));

                dy = diff(y);
                if all( dy > 0)
                    Th = interp1( y, x, app.G00,"linear","extrap");
                    app.GradLUT_G00_LineObj.XData = Th;
                    app.GradLUT_G00_LineObj.YData = app.G00;
                    app.GradLUT_TextObj.String = sprintf( "%0.2f mm", Th);
                end
            end
            app.PlotAllInProgress = false;
        end

        function ZeroTabPlotAll(app)
            x = 0:length( app.Signal)-1;
            app.ZeroTabSignalLineObj.XData = x;
            app.ZeroTabSignalLineObj.YData = app.Signal;

            % оценка начальной зоны (подключение/отключение датчика)
            % DEBUG!
            s = double( app.Signal);
            ds = diff( s);
            std_tail = max( std( ds(18000:end)), 1);
            app.RespSTDEditField.Value = std( ds(50:100)) / std_tail;


            s = double( app.Signal);
            slen = length(s);
            s_fft = fft(s);
            sf = app.LPfreqEditField.Value;
            fp = round( sf / 100 * slen);
            fft_mask = ones(1, fp);

            app.SignalFiltered = ifft( s_fft(1:fp) .* fft_mask, slen, 'symmetric');
            app.SignalFilteredLineObj.XData = x;
            app.SignalFilteredLineObj.YData = app.SignalFiltered;


            start = max( 1, app.ZeroTabLeftBound);
            %                 [y, x] = max( app.Signal( start : ( start + 1000)));
            %                 app.ZeroTabZeroPosX = x + start -2;
            %                 app.ZeroTabZeroPosY = y;

            app.ZeroTabThresholdLineObj.XData = app.ZeroTabUIAxes.XLim;
            app.ZeroTabThresholdLineObj.YData = [ app.ZeroTabThreshold app.ZeroTabThreshold];

            app.ZeroTabZeroPosX = 0;
            app.ZeroTabZeroPosY = NaN;
            s = int16( app.Signal( start : ( start + 1000)));
            z = mean(s);

            if app.ZeroTabThreshold > z
                c = ( s >= app.ZeroTabThreshold);
            else
                c = ( s <= app.ZeroTabThreshold);
            end

            ThresholdIdx = find( c, 1);
            if ~isempty( ThresholdIdx)

                tmps = s( ThresholdIdx:end);

                if app.ZeroTabThreshold > z
                    c = ( tmps <= z);
                else
                    c = ( tmps >= z);
                end

                ZeroIdx = find( c, 1);
                if ~isempty( ZeroIdx)
                    app.ZeroTabZeroPosX = ThresholdIdx + ZeroIdx + start -3;
                    y = z;
                    app.ZeroTabZeroPosY = y;
                end
            end

            app.ZeroTabZeroMarkerLineObj.XData = app.ZeroTabZeroPosX;
            app.ZeroTabZeroMarkerLineObj.YData = app.ZeroTabZeroPosY;

            s = double( app.Signal( start : ( start + 1000)));
            g = round( app.ZeroTabGainStatic / 16);
            g = GainAdjust( s, g);

            if app.AutoGainCheckBox.Value == true
                app.ZeroTabGainStatic = g *16;
            end

            x = [ app.ZeroTabLeftBound, app.ZeroTabLeftBound];
            y = app.ZeroTabUIAxes.YLim;
            app.ZeroTabLeftBoundLineObj.XData = x;
            app.ZeroTabLeftBoundLineObj.YData = y;

            x = app.ZeroTabUIAxes.XLim;
            y = [ app.ZeroTabGainStatic app.ZeroTabGainStatic];
            app.ZeroTabGainStaticLineObj.XData = x;
            app.ZeroTabGainStaticLineObj.YData = y;

            app.ZeroTabGainStaticTextObj.String = sprintf( "%0.0f mV", app.ZeroTabGainStatic *3000 / 4096);
            app.ZeroTabGainStaticTextObj.Position = [ app.ZeroTabUIAxes.XLim(2) app.ZeroTabGainStatic];

            app.ZeroTabLeftBoundTextObj.String = sprintf( "%0.0f", app.ZeroTabLeftBound);
            app.ZeroTabLeftBoundTextObj.Position = [ app.ZeroTabLeftBound app.ZeroTabUIAxes.YLim(2)];

            app.ZeroTabZeroMarkerTextObj.String = sprintf( "   %0.0f", app.ZeroTabZeroPosX);
            app.ZeroTabZeroMarkerTextObj.Position = [ app.ZeroTabZeroPosX, app.ZeroTabZeroPosY];
        end


        function SettingsAssign(app)
            if convertCharsToStrings( app.TabGroup.SelectedTab.Title) == "Zero"
                ZeroTabSettingsAssign(app);
                return;
            end

            switch app.SettingsIdx %app.SettingsButtonGroup.SelectedObject.Text
                case 3
                    app.Dpos = max( min( app.MouseButtonDownX, 19900), 0);
                case 2
                    app.Threshold = max( min( app.MouseButtonDownY, 4095), 0);
                case 1
                    xl = app.UIAxes.XLim;
                    %yl = app.UIAxes.YLim;
                    xm = app.MouseButtonDownX;
                    ym = app.MouseButtonDownY;

                    xd = ( xl(2) - xl(1)) / 50;
                    x_left = max( xm - xd, xl(1));
                    x_right = min( xm + xd, xl(2));

                    if( app.GainCurvePointSelected == false)
                        app.GainCurvePointSelectedIdx = find( ( app.GainCurveX >= x_left) & ( app.GainCurveX <= x_right));
                    end

                    if ~isempty( app.GainCurvePointSelectedIdx)
                        app.GainCurvePointSelected = true;
                        idx = app.GainCurvePointSelectedIdx(1);

                        % XX крайних точек остаются на своих местах
                        % (могут меняться только YY)
                        if idx > 1 && idx < 10
                            l = app.GainCurveX( idx -1) +18;
                            r = app.GainCurveX( idx +1) -18;
                            c = round( xm / 18) *18;
                            if( c >= l && c <= r)
                                app.GainCurveX( idx) = c;
                            end
                        end

                        app.GainCurveY( idx) = round( ym /16) *16;
                    else
                        app.GainCurvePointSelected = false;
                    end
            end

            function ZeroTabSettingsAssign(app)
                switch app.ZeroTabSettingsIdx
                    case 1
                        app.ZeroTabGainStatic = max( min( app.MouseButtonDownY, 4095), 0);
                    case 2
                        app.ZeroTabLeftBound = round( max( min( app.MouseButtonDownX, 19900), 0));
                end
            end

        end

        function UIFigureButton( app)

            if convertCharsToStrings( app.TabGroup.SelectedTab.Title) == "LUT"
                yyaxis( app.UIAxes,"right");
                app.MouseButtonDownX = app.UIAxes.CurrentPoint(1, 1);
                app.MouseButtonDownY = app.UIAxes.CurrentPoint(1, 2);
                xl = app.UIAxes.XLim;
                yl = app.UIAxes.YLim;
            else
                app.MouseButtonDownX = app.ZeroTabUIAxes.CurrentPoint(1, 1);
                app.MouseButtonDownY = app.ZeroTabUIAxes.CurrentPoint(1, 2);
                xl = app.ZeroTabUIAxes.XLim;
                yl = app.ZeroTabUIAxes.YLim;
            end

            xm = app.MouseButtonDownX;
            ym = app.MouseButtonDownY;

            if xm > xl(1) && xm < xl(2) && ...
                    ym > yl(1) && ym < yl(2)

                app.MouseButton = 1;

                SettingsAssign(app);
                PlotAll(app);
            end
        end

        function GainCurveLineObjButtonDownFcn( ~, ~)
        end

        function SerialObjErrFcn(app, ~, ~)
            app.IsSerialConnected = false;
            app.ConnectButton.Text = "Connect";
            app.ConnectButton.Enable = true;
            app.StoreontargetCheckBox.Enable = false;
            app.StoreontargetCheckBox.Value = false;
        end

        function SerialObjFcn_1(app, ~, ~)
            if app.SerialObjFcnInProgress == true
                %return;
            end

            app.SerialObjFcnInProgress = true;

            data = read( app.SerialObj, (41152+64), "uint8");
            SendSettings(app);

            flush(app.SerialObj);

            tmpdata = reshape( uint8( data), [64, 644]);

            ds = 0;
            for PacketNum = 1:644
                Packet = tmpdata( :, PacketNum);
                if isequal( Packet', app.SerialHeader)
                    ds = 41152;
                    break;
                end
            end

            data = circshift( data, -PacketNum *64);

            if ds == 41152
                %data = data( ( st(1) +64 +1):st(2));

                data16 = typecast( uint8( data), 'uint16');
                app.Signal = data16(1:19944);
                app.G00 = double( data16(19945));

                app.PlotAll();

                app.IsSerialConnected = true;
                app.ConnectButton.Text = "Disconnect";
                app.ConnectButton.Enable = true;

                app.StoreontargetCheckBox.Enable = true;
            end

            app.SerialObjFcnInProgress = false;

            return;
        end

        function SerialObjFcn_old(app, ~, ~)
            if app.SerialObjFcnInProgress == true
                %return;
            end

            app.SerialObjFcnInProgress = true;

            data = read( app.SerialObj, (41152+64)*2, "uint8");
            SendSettings(app);

            st = [0 0]; si = 1;
            for StartIdx = 0:64:((41152+64)*2 - 64*2)
                packet = data( StartIdx +1 : StartIdx +64);
                if isequal( packet, app.SerialHeader)
                    st(si) = StartIdx;
                    si = si +1;
                    if si == 3
                        break;
                    end
                end
            end

            ds = st(2) - st(1);
            %app.s = [ app.s ds];

            if ds == 41152+64
                data = data( ( st(1) +64 +1):st(2));

                data16 = typecast( uint8( data), 'uint16');
                app.Signal = data16(1:19944);
                app.G00 = double( data16(19945));

                status_buffer = data(length(data)-64+1:length(data));
                app.store = status_buffer(1);

                if app.store == 1
                    if app.StoreontargetCheckBox.Value == true
                        app.StoreontargetCheckBox.Value = false;
                        msgbox("Operation Done");
                    end
                end

                app.PlotAll();

                app.IsSerialConnected = true;
                app.ConnectButton.Text = "Disconnect";
                app.ConnectButton.Enable = true;

                app.StoreontargetCheckBox.Enable = true;
            end

            %             nb = app.SerialObj.NumBytesAvailable;
            %             if nb > 41152*2
            %                 %flush( app.SerialObj, "input");
            %                 read( app.SerialObj, nb - 41152*2, "uint8");
            %             end

            app.SerialObjFcnInProgress = false;

            return;
        end

        function SerialObjFcn(app, ~, ~)

            if app.SerialObjFcnInProgress == true
                return;
            end

            app.SerialObjFcnInProgress = true;
            StartPacketReceived = false;

            while( app.SerialObj.NumBytesAvailable >= 64)
                packet = uint8( read( app.SerialObj, 64, "uint8"));
                if isequal( packet, app.SerialHeader)
                    StartPacketReceived = true;
                    break;
                end
            end

            if( StartPacketReceived && app.SerialObj.NumBytesAvailable >= 41088)
                data = read( app.SerialObj, 41088/2, "uint16");
                app.Signal = data(1:19944); % сигнал с АЦП
                app.G00 = data(19945);

                SendSettings(app);
                app.PlotAll();
            end

            flush( app.SerialObj, "input");
            app.SerialObjFcnInProgress = false;
        end

        function SendSettings( app)
            % отправим в контроллер текущие настройки
            if convertCharsToStrings( app.TabGroup.SelectedTab.Title) == "LUT"
                GainCurveTable = interp1( app.GainCurveX, app.GainCurveY, 0:19943, "linear"); % кривая усиления
                DAC1_Table = GainCurveTable(1:18:end) /16;
                zw = uint8( app.ZondWidth); % ширина ZondB
            else
                g = app.ZeroTabGainStatic;
                DAC1_Table = uint8( ones(1, 1108) * g / 16);
                zw = uint8( app.ZeroTabZondWidth); % ширина ZondB
            end

            th = typecast( uint16( app.Threshold), 'uint8'); % порог компаратора
            sp = uint8( app.SingleDual); % Single/Dual
            rm = uint8( 0); % готов измерять (есть аккустический контакт, усиление подстроено)
            gc = uint8( app.GeneratorChannel); % канал генератора (1 или 2)
            d =  typecast( uint32( app.Dpos *2), 'uint8');
            ForGrad = [ DAC1_Table'; th'; zw; sp; rm; gc; d'];

            SettingsTransmitSign = 0x0000;
            if app.StoreontargetCheckBox.Value == true
                SettingsTransmitSign = 0x3663;
            end

            valueSet = {'A5','A10','W5E'};
            keySet= [0 ,1 ,2];

            M = containers.Map(keySet,valueSet);

            probe_name_buf = zeros(4, 1);
            probe_name_index = app.ProbeDropDown.Value;
            probe_name = M(probe_name_index);

            for i = 1 : length(probe_name)
                probe_name_buf(i) = probe_name(i);
            end

            probe_name_buf = probe_name_buf(1:4);

            s = typecast( SettingsTransmitSign, 'uint8')';
            ForProbeStateFlow = app.PackSettings();
            ToSend = [ForGrad; s; probe_name_buf; ForProbeStateFlow'];

            write( app.SerialObj, ToSend, 'uint8');
        end


        function PE_Settings_uint8 = PackSettings(app)
            V_uint8 = typecast( single( app.Velocity), 'uint8'); % скорость УЗ
            l = size( app.GradTable); l_uint8 = uint8( l(1)); % количество строк GLUT
            GLUT_mat = cell2mat( app.GradTable);
            idx = ( l_uint8 +1) : app.GradTableMaxSize;
            if ~isempty( idx)
                GLUT_mat( idx, :) = 0; % заполним нулями оставшиеся ячейки
            end

            THLUT_mat = cell2mat( app.ThresholdTable( :, 2));
            THLUT_uint8 = typecast( reshape( uint16( THLUT_mat).', [], 1), 'uint8');

            GLUT_uint8 = typecast( reshape( single( GLUT_mat).', [], 1), 'uint8');

            GainCurveTable = interp1( app.GainCurveX, app.GainCurveY, 0:19943, "linear"); % кривая усиления
            DAC1_uint8 = uint8( GainCurveTable(1:18:end) /16);
            Th_uint8 = typecast( uint16( app.Threshold), 'uint8');
            D_uint8 = typecast( uint16( app.Dpos), 'uint8');

            ZeroLB_uint8 = typecast( uint16( app.ZeroTabLeftBound), 'uint8');
            ZeroDAC1_uint8 = uint8( app.ZeroTabGainStatic / 16);
            ZeroZond_uint8 = uint8( app.ZeroTabZondWidth);
            ZeroPos_uint8 = typecast( single( app.ZeroTabRedZero + app.ZeroTabBlueZero ), 'uint8');
            ZeroThreshold_uint8 =  typecast( uint16( app.ZeroTabThreshold), 'uint8');

            PE_Settings_uint8 = [ V_uint8, GLUT_uint8', l_uint8, uint8(0), DAC1_uint8, D_uint8, Th_uint8, ...
                ZeroLB_uint8, ZeroDAC1_uint8, ZeroZond_uint8, uint8(0), uint8(0), ZeroPos_uint8, ...
                ZeroThreshold_uint8, THLUT_uint8'];

        end
        
        function count = SaveToWorkspaceDataset(app, C_NC)
            if C_NC == 0
                name = 'dataset_NC';
            else
                name = 'dataset_C';
            end

            s = struct();
            s.Signal = double( app.Signal);
            s.RespSTD = app.RespSTDEditField.Value;
            s.Zond = app.ZeroTabZondWidth;
            s.SingleDual = app.SingleDual;
            s.GeneratorChannel = app.GeneratorChannel;
            s.Gain = app.ZeroTabGainStatic;

            try
                dataset = evalin('base', name);                
                % Дописываем новый сигнал новой строкой снизу
                dataset( end +1) = s;
            catch
                % Если данных еще нет в Workspace, создаем их (это первый сигнал)
                dataset = s;
            end
            % Отправляем данные в Workspace
            assignin('base', name, dataset);

            count = length(dataset);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create timer object
            %             app.myTimer = timer(...
            %                 'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
            %                 'Period', 1, ...                     % Period is 1 second
            %                 'BusyMode', 'queue',...              % Queue timer callbacks when busy
            %                 'TimerFcn', @app.myTimerFcn);        % Specify callback function
            %start(app.myTimer);

            app.PlotInit();
            app.ZeroTabPlotInit();

            l = serialportlist("available");
            app.COMportDropDown.Editable = false;
            app.COMportDropDown.Items = l;
            if isempty(l)
                app.ConnectButton.Enable = false;
            end

            %app.SerialObj = serialport( "COM4", 115200, "Timeout", 0.2);

            app.UIGradTable.Data = app.GradTable;
            app.UIGradTable.ColumnFormat = {'bank' 'numeric'};

            app.G00EditField.Editable = false;
            app.G00EditField.Value = app.G00;

            app.ZeroTabThresholdEditField.Value = app.ZeroTabThreshold;
            app.ZeroTabZondWidthEditField.Value = app.ZeroTabZondWidth;
            %flush( app.SerialObj, "input");

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop timer, then delete timer and app
            %stop(app.myTimer);
            %delete(app.myTimer);
            if ~isempty( app.SerialObj)
                configureCallback( app.SerialObj, "off");
                delete(app.SerialObj);
            end
            delete(app);
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if( app.MouseButton)
                UIFigureButton(app);
            end
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            UIFigureButton(app);
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            app.MouseButton = 0;
            app.GainCurvePointSelected = false;
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            %            app.MouseButton = 1;
            %            PlotV(app);
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

        end

        % Value changed function: SingleDualSwitch
        function SingleDualSwitchValueChanged(app, event)
            app.SingleDual = app.SingleDualSwitch.Value;
        end

        % Value changed function: ZondwidthEditField
        function ZondwidthEditFieldValueChanged(app, event)
            app.ZondWidth = app.ZondwidthEditField.Value;
        end

        % Button pushed function: MinusButton
        function MinusButtonPushed(app, event)
            l = size( app.GradTable); l = l(1);
            if l > 2 && app.GradTableCurrentIdx
                app.GradTable( app.GradTableCurrentIdx, :) = []; % удалим строчку
                %app.GradTable = app.GradTable(1:l-1, :); % удалим последнюю строку
                app.UIGradTable.Data = app.GradTable;

                app.PlotGradLUT();
            end
        end

        % Button pushed function: PlusButton
        function PlusButtonPushed(app, event)
            l = size( app.GradTable); l = l(1);
            if l < app.GradTableMaxSize
                app.GradTable( l +1, :) = app.GradTable( l, :); % добавим строку
                app.UIGradTable.Data = app.GradTable;

                app.PlotGradLUT();
            end

        end

        % Cell edit callback: UIGradTable
        function UIGradTableCellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            t = cell2mat( app.UIGradTable.Data(:, 1));
            [~, ti] = sort(t);
            app.GradTable = app.UIGradTable.Data( ti, :);
            app.UIGradTable.Data = app.GradTable;

            app.PlotGradLUT();
        end

        % Cell selection callback: UIGradTable
        function UIGradTableCellSelection(app, event)
            app.GradTableCurrentIdx = 0;
            app.UIGradTable.Data = app.GradTable;
            indices = event.Indices;
            s = size( indices);
            if s(1)
                i = indices(2);
                if s(1) == 1 % && i == 2 % выбрали одну ячейку во втором столбце
                    app.GradTableCurrentIdx = indices(1); % номер строки
                end
            end
        end

        % Callback function
        function UIGradTableDisplayDataChanged(app, event)
            %newDisplayData = app.UIGradTable.DisplayData;

        end

        % Button pushed function: G00_toCodeButton
        function G00_toCodeButtonPushed(app, event)
            if app.GradTableCurrentIdx
                app.UIGradTable.Data( app.GradTableCurrentIdx, 2) = {app.G00};
                app.GradTable = app.UIGradTable.Data;

                app.PlotGradLUT();
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if app.IsSerialConnected == false
                app.ConnectButton.Enable = false;
                app.COM_Port = app.COMportDropDown.Value;
                app.SerialObj = serialport( app.COM_Port, 115200, "Timeout", 0.2);
                app.SerialObj.ErrorOccurredFcn = @app.SerialObjErrFcn;
                %configureCallback( app.SerialObj, "byte", 41152, @app.SerialObjFcn_1);
                configureCallback( app.SerialObj, "byte", 41152, @app.SerialObjFcn_old);
            else
                if ~isempty( app.SerialObj)
                    configureCallback( app.SerialObj, "off");
                    delete(app.SerialObj);
                    app.SerialObj = [];
                end
                app.IsSerialConnected = false;
                app.ConnectButton.Text = "Connect";
                app.StoreontargetCheckBox.Enable = false;
                app.StoreontargetCheckBox.Value = false;
            end
        end

        % Callback function: FileSave
        function FileSaveClicked(app, event)
            GCX = app.GainCurveX;
            GCY = app.GainCurveY;
            GLUT = app.GradTable;
            THLUT = app.ThresholdTable;
            D = app.Dpos;
            Th = app.Threshold;
            Z = app.ZondWidth;
            V = app.Velocity;

            ZeroLB = app.ZeroTabLeftBound;
            ZeroGain = app.ZeroTabGainStatic;
            ZeroZond = app.ZeroTabZondWidth;
            ZeroPos = app.ZeroTabBlueZero + app.ZeroTabRedZero; %app.ZeroTabZeroPosX;
            ZeroRedPos = app.ZeroTabRedZero;
            ZeroBluePos = app.ZeroTabBlueZero;
            ZeroThreshold = app.ZeroTabThreshold;

            Settings_uint8 = app.PackSettings();

            [ file, path] = uiputfile( 'ProbeB2.mat');
            if ~( isequal(file,0) || isequal(path,0))
                save( [ path file], 'GCX', 'GCY', 'GLUT', 'D', 'Th', 'Z', 'V', ...
                    'ZeroLB', 'ZeroGain', 'ZeroZond', 'ZeroPos', 'ZeroThreshold', 'ZeroRedPos', "ZeroBluePos", "THLUT");

                fname = file( 1:length( file) -3);
                fID = fopen( [ path fname 'bin'], 'w');
                fwrite( fID, Settings_uint8, "uint8");
                fclose( fID);
            end
        end

        % Callback function: FileOpen
        function FileOpenClicked(app, event)
            uiopen("load");
            if exist('GCX', 'var') && exist('GCY', 'var') && exist('GLUT', 'var') && ...
                    exist('D', 'var') && exist('Th', 'var') && exist('Z', 'var')
                app.GainCurveX = GCX;
                app.GainCurveY = GCY;
                app.GradTable = GLUT;
                app.UIGradTable.Data = GLUT;
                app.Dpos = D;
                app.Threshold = Th;
                app.ZondWidth = Z;
                app.ZondwidthEditField.Value = app.ZondWidth;
            end

            if exist('V', 'var')
                app.Velocity = V;
                app.VelocityEditField.Value = V;
            end

            if exist('ZeroLB', 'var') && exist('ZeroGain', 'var') && exist( 'ZeroZond', 'var') && exist( 'ZeroPos', 'var')
                app.ZeroTabLeftBound = ZeroLB;
                app.ZeroTabGainStatic = ZeroGain;
                app.ZeroTabZondWidth = ZeroZond;
                app.ZeroTabZeroPosX = ZeroPos;
                app.ZeroTabZondWidthEditField.Value = app.ZeroTabZondWidth;
            end

            if exist('ZeroThreshold', 'var')
                app.ZeroTabThreshold = ZeroThreshold;
                app.ZeroTabThresholdEditField.Value = ZeroThreshold;
            end

            if exist( 'ZeroRedPos', 'var') && exist( 'ZeroBluePos', 'var')
                app.ZeroTabBlueZero = ZeroBluePos;
                app.ZeroTabRedZero = ZeroRedPos;
                app.RedZeroEditField.Value = ZeroRedPos;
                app.BlueZeroEditField.Value = ZeroBluePos;
            end

            if exist( 'THLUT', 'var')
                app.ThresholdTable = THLUT;
                app.UIThresholdTable.Data = THLUT;
            end

            app.PlotGradLUT();
            app.PlotAll();
            app.ZeroTabPlotAll();
        end

        % Value changed function: ZeroTabZondWidthEditField
        function ZeroTabZondWidthEditFieldValueChanged(app, event)
            app.ZeroTabZondWidth = app.ZeroTabZondWidthEditField.Value;

        end

        % Value changed function: VelocityEditField
        function VelocityEditFieldValueChanged(app, event)
            app.Velocity = app.VelocityEditField.Value;
        end

        % Value changed function: ZeroTabThresholdEditField
        function ZeroTabThresholdEditFieldValueChanged(app, event)
            app.ZeroTabThreshold = app.ZeroTabThresholdEditField.Value;
            app.PlotAll();
        end

        % Value changed function: GeneratorChannelSwitch
        function GeneratorChannelSwitchValueChanged(app, event)
            app.GeneratorChannel = app.GeneratorChannelSwitch.Value;

            if app.GeneratorChannel == 0
                FontColor = [1 0 0]; % Red
            else
                FontColor = [0.07 0.62 1]; % Blue
            end

            app.Zero_toCodeButton.FontColor = FontColor;
        end

        % Button pushed function: updateButton
        function updateButtonPushed(app, event)
            l = serialportlist("available");
            app.COMportDropDown.Editable = false;
            app.COMportDropDown.Items = l;
        end

        % Button pushed function: Zero_toCodeButton
        function Zero_toCodeButtonPushed(app, event)
            if app.GeneratorChannel == 0
                app.ZeroTabRedZero = app.ZeroTabZeroPosX;
                app.RedZeroEditField.Value = app.ZeroTabRedZero;
            else
                app.ZeroTabBlueZero = app.ZeroTabZeroPosX;
                app.BlueZeroEditField.Value = app.ZeroTabBlueZero;
            end
        end

        % Cell selection callback: UIThresholdTable
        function UIThresholdTableCellSelection(app, event)
            app.ThresholdTableCurrentIdx = 0;
            app.UIThresholdTable.Data = app.ThresholdTable;
            indices = event.Indices;
            s = size( indices);
            if s(1)
                i = indices(2);
                if s(1) == 1 % && i == 2 % выбрали одну ячейку во втором столбце
                    app.ThresholdTableCurrentIdx = indices(1); % номер строки
                end
            end


        end

        % Cell edit callback: UIThresholdTable
        function UIThresholdTableCellEdit(app, event)
            %indices = event.Indices;
            %newData = event.NewData;
            app.ThresholdTable = app.UIThresholdTable.Data;
        end

        % Button pushed function: Threshold2CodeButton
        function Threshold2CodeButtonPushed(app, event)
            if app.ThresholdTableCurrentIdx
                app.UIThresholdTable.Data( app.ThresholdTableCurrentIdx, 2) = { round( app.Threshold)};
                app.ThresholdTable = app.UIThresholdTable.Data;
            end
        end

        % Button pushed function: NC_Button
        function NC_ButtonPushed(app, event)
            n = app.SaveToWorkspaceDataset( 0);
            app.NC_Button.Text = sprintf("-x- %d", n);
        end

        % Button pushed function: C_Button
        function C_ButtonPushed(app, event)
            n = app.SaveToWorkspaceDataset( 1);
            app.C_Button.Text = sprintf("-o- %d", n);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.FileOpen = uipushtool(app.Toolbar);
            app.FileOpen.ClickedCallback = createCallbackFcn(app, @FileOpenClicked, true);
            app.FileOpen.Icon = 'file_open.png';
            app.FileSave = uipushtool(app.Toolbar);
            app.FileSave.ClickedCallback = createCallbackFcn(app, @FileSaveClicked, true);
            app.FileSave.Icon = 'file_save.png';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.SingleDualSwitch = uiswitch(app.UIFigure, 'slider');
            app.SingleDualSwitch.Items = {'', ''};
            app.SingleDualSwitch.ItemsData = [0 1];
            app.SingleDualSwitch.ValueChangedFcn = createCallbackFcn(app, @SingleDualSwitchValueChanged, true);
            app.SingleDualSwitch.Value = 1;
            app.COMportDropDownLabel = uilabel(app.UIFigure);
            app.COMportDropDownLabel.Text = 'COM port';
            app.COMportDropDown = uidropdown(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LUTTab = uitab(app.TabGroup);
            app.LUTTab.Title = 'LUT';
            app.UIAxesGradTable = uiaxes(app.LUTTab);
            title(app.UIAxesGradTable, 'Grad LUT')
            xlabel(app.UIAxesGradTable, 'Thickness, mm')
            ylabel(app.UIAxesGradTable, 'Code')
            zlabel(app.UIAxesGradTable, 'Z')
            app.UIAxes = uiaxes(app.LUTTab);
            title(app.UIAxes, 'Signal')
            xlabel(app.UIAxes, 'x10 ns')
            ylabel(app.UIAxes, '12 bit code')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YLim = [0 4095];
            app.UIAxes.YAxisLocation = 'right';
            app.UIGradTable = uitable(app.LUTTab);
            app.UIGradTable.ColumnName = {'mm'; 'Code'};
            app.UIGradTable.RowName = {};
            app.UIGradTable.ColumnEditable = true;
            app.UIGradTable.CellEditCallback = createCallbackFcn(app, @UIGradTableCellEdit, true);
            app.UIGradTable.CellSelectionCallback = createCallbackFcn(app, @UIGradTableCellSelection, true);
            app.PlusButton = uibutton(app.LUTTab, 'push');
            app.PlusButton.ButtonPushedFcn = createCallbackFcn(app, @PlusButtonPushed, true);
            app.PlusButton.Text = '+';
            app.MinusButton = uibutton(app.LUTTab, 'push');
            app.MinusButton.ButtonPushedFcn = createCallbackFcn(app, @MinusButtonPushed, true);
            app.MinusButton.Text = '-';
            app.G00_toCodeButton = uibutton(app.LUTTab, 'push');
            app.G00_toCodeButton.ButtonPushedFcn = createCallbackFcn(app, @G00_toCodeButtonPushed, true);
            app.G00_toCodeButton.Text = '>>';
            app.G00EditFieldLabel = uilabel(app.LUTTab);
            app.G00EditFieldLabel.Text = 'G00';
            app.G00EditField = uieditfield(app.LUTTab, 'numeric');
            app.G00EditField.ValueDisplayFormat = '%.0f';
            app.ZondwidthEditFieldLabel = uilabel(app.LUTTab);
            app.ZondwidthEditFieldLabel.Text = 'Zond width';
            app.ZondwidthEditField = uieditfield(app.LUTTab, 'numeric');
            app.ZondwidthEditField.Limits = [0 70];
            app.ZondwidthEditField.RoundFractionalValues = 'on';
            app.ZondwidthEditField.ValueDisplayFormat = '%.0f';
            app.ZondwidthEditField.ValueChangedFcn = createCallbackFcn(app, @ZondwidthEditFieldValueChanged, true);
            app.ZondwidthEditField.Value = 10;
            app.Label = uilabel(app.LUTTab);
            app.Label.Text = 'x10 ns';
            app.VelocityEditFieldLabel = uilabel(app.LUTTab);
            app.VelocityEditFieldLabel.Text = 'Velocity';
            app.VelocityEditField = uieditfield(app.LUTTab, 'numeric');
            app.VelocityEditField.Limits = [1000 7000];
            app.VelocityEditField.ValueDisplayFormat = '%.0f';
            app.VelocityEditField.ValueChangedFcn = createCallbackFcn(app, @VelocityEditFieldValueChanged, true);
            app.VelocityEditField.Value = 5920;
            app.msLabel = uilabel(app.LUTTab);
            app.msLabel.Text = 'm/s';
            app.UIThresholdTable = uitable(app.LUTTab);
            app.UIThresholdTable.ColumnName = {'Threshold'; 'Code'};
            app.UIThresholdTable.RowName = {};
            app.UIThresholdTable.ColumnEditable = [false true];
            app.UIThresholdTable.CellEditCallback = createCallbackFcn(app, @UIThresholdTableCellEdit, true);
            app.UIThresholdTable.CellSelectionCallback = createCallbackFcn(app, @UIThresholdTableCellSelection, true);
            app.Threshold2CodeButton = uibutton(app.LUTTab, 'push');
            app.Threshold2CodeButton.ButtonPushedFcn = createCallbackFcn(app, @Threshold2CodeButtonPushed, true);
            app.Threshold2CodeButton.Text = '<<';
            app.ZeroTab = uitab(app.TabGroup);
            app.ZeroTab.Title = 'Zero';
            app.ZeroTabUIAxes = uiaxes(app.ZeroTab);
            title(app.ZeroTabUIAxes, 'Signal')
            xlabel(app.ZeroTabUIAxes, 'x10 ns')
            ylabel(app.ZeroTabUIAxes, 'ADC code')
            zlabel(app.ZeroTabUIAxes, 'Z')
            app.ZeroTabUIAxes.YLim = [0 4096];
            app.ZeroTabLabel = uilabel(app.ZeroTab);
            app.ZeroTabLabel.Text = 'x10 ns';
            app.ZondwidthEditField_2Label = uilabel(app.ZeroTab);
            app.ZondwidthEditField_2Label.Text = 'Zond width';
            app.ZeroTabZondWidthEditField = uieditfield(app.ZeroTab, 'numeric');
            app.ZeroTabZondWidthEditField.Limits = [0 70];
            app.ZeroTabZondWidthEditField.RoundFractionalValues = 'on';
            app.ZeroTabZondWidthEditField.ValueDisplayFormat = '%.0f';
            app.ZeroTabZondWidthEditField.ValueChangedFcn = createCallbackFcn(app, @ZeroTabZondWidthEditFieldValueChanged, true);
            app.ZeroTabZondWidthEditField.Value = 10;
            app.AutoGainCheckBox = uicheckbox(app.ZeroTab);
            app.AutoGainCheckBox.Text = 'Auto Gain';
            app.ThresholdEditFieldLabel = uilabel(app.ZeroTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ZeroTabThresholdEditField = uieditfield(app.ZeroTab, 'numeric');
            app.ZeroTabThresholdEditField.Limits = [500 3500];
            app.ZeroTabThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ZeroTabThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ZeroTabThresholdEditFieldValueChanged, true);
            app.ZeroTabThresholdEditField.Value = 2500;
            app.ZeroLabel = uilabel(app.ZeroTab);
            app.ZeroLabel.Text = 'Zero ●1';
            app.RedZeroEditField = uieditfield(app.ZeroTab, 'numeric');
            app.RedZeroEditField.ValueDisplayFormat = '%.0f';
            app.Zero2Label = uilabel(app.ZeroTab);
            app.Zero2Label.Text = 'Zero ●2';
            app.BlueZeroEditField = uieditfield(app.ZeroTab, 'numeric');
            app.BlueZeroEditField.ValueDisplayFormat = '%.0f';
            app.Zero_toCodeButton = uibutton(app.ZeroTab, 'push');
            app.Zero_toCodeButton.ButtonPushedFcn = createCallbackFcn(app, @Zero_toCodeButtonPushed, true);
            app.Zero_toCodeButton.Text = '<<';
            app.LPfreqEditFieldLabel = uilabel(app.ZeroTab);
            app.LPfreqEditFieldLabel.Text = 'LP freq';
            app.LPfreqEditField = uieditfield(app.ZeroTab, 'numeric');
            app.LPfreqEditField.Limits = [1 50];
            app.LPfreqEditField.RoundFractionalValues = 'on';
            app.LPfreqEditField.ValueDisplayFormat = '%0.0f';
            app.LPfreqEditField.Value = 1;
            app.ZeroTabLabel_2 = uilabel(app.ZeroTab);
            app.ZeroTabLabel_2.Text = 'MHz';
            app.RespSTDEditFieldLabel = uilabel(app.ZeroTab);
            app.RespSTDEditFieldLabel.Text = 'RespSTD';
            app.RespSTDEditField = uieditfield(app.ZeroTab, 'numeric');
            app.RespSTDEditField.ValueDisplayFormat = '%.0f';
            app.RespSTDEditField.Editable = 'off';
            app.C_Button = uibutton(app.ZeroTab, 'push');
            app.C_Button.ButtonPushedFcn = createCallbackFcn(app, @C_ButtonPushed, true);
            app.C_Button.Text = '-o-';
            app.NC_Button = uibutton(app.ZeroTab, 'push');
            app.NC_Button.ButtonPushedFcn = createCallbackFcn(app, @NC_ButtonPushed, true);
            app.NC_Button.Text = '-x-';
            app.StoreontargetCheckBox = uicheckbox(app.UIFigure);
            app.StoreontargetCheckBox.Text = 'Store on target';
            app.GeneratorChannelSwitch = uiswitch(app.UIFigure, 'slider');
            app.GeneratorChannelSwitch.Items = {'', ''};
            app.GeneratorChannelSwitch.ItemsData = [0 1];
            app.GeneratorChannelSwitch.ValueChangedFcn = createCallbackFcn(app, @GeneratorChannelSwitchValueChanged, true);
            app.GeneratorChannelSwitch.Value = 0;
            app.updateButton = uibutton(app.UIFigure, 'push');
            app.updateButton.ButtonPushedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.updateButton.Text = 'update';
            app.ProbeDropDownLabel = uilabel(app.UIFigure);
            app.ProbeDropDownLabel.Text = 'Probe';
            app.ProbeDropDown = uidropdown(app.UIFigure);
            app.ProbeDropDown.Items = {'A5', 'A10', 'W5E'};
            app.ProbeDropDown.ItemsData = [0 1 2];
            app.ProbeDropDown.Value = 0;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '●1';
            app.ReceiverLabel = uilabel(app.UIFigure);
            app.ReceiverLabel.Text = 'Receiver';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '●1';
            app.GeneratorLabel = uilabel(app.UIFigure);
            app.GeneratorLabel.Text = 'Generator';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '●2';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '●2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bulat7GradRelayApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end